classdef weather_impact_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FirstyearEditFieldLabel         matlab.ui.control.Label
        firstyear                       matlab.ui.control.NumericEditField
        LastyearEditFieldLabel          matlab.ui.control.Label
        lastyear                        matlab.ui.control.NumericEditField
        RunObservationButton            matlab.ui.control.Button
        LowerquantileEditFieldLabel     matlab.ui.control.Label
        Lowerquantile                   matlab.ui.control.NumericEditField
        HigherquantileEditFieldLabel    matlab.ui.control.Label
        Higherquantile                  matlab.ui.control.NumericEditField
        dependent                       matlab.ui.control.Button
        DependentvariableLabel          matlab.ui.control.Label
        ObservationtypeButtonGroup      matlab.ui.container.ButtonGroup
        SolarwindcombinedButton         matlab.ui.control.RadioButton
        SolaronlyButton                 matlab.ui.control.RadioButton
        WindonlyButton                  matlab.ui.control.RadioButton
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ExplanatoryVar1                 matlab.ui.control.Button
        ExplanatoryVar2                 matlab.ui.control.Button
        ExplanatoryvariablesolarLabel_2  matlab.ui.control.Label
        ExplanatoryvariablewindLabel_2  matlab.ui.control.Label
        ClosecurrentMATLABfiguresButton  matlab.ui.control.Button
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        path
        type % Description
        quantile % Description
        algo % Description
        model % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunObservationButton
        function RunObservationButtonPushed(app, event)
            %% dark doldrum
            
            if strcmp(app.dependent.Text, 'Browse variable...')
                [app.dependent.Text, app.path] = uigetfile('*.mat');
                app.dependent.FontWeight = 'Normal';
            end
            
            app.algo = 'quantile';
            app.type = 'dd';
            app.quantile = app.Lowerquantile.Value;
            
            [days_sunDD, days_windDD, days_DD] = weather_day_identifier(app);
            
%             clear dt 
            
            %% bright breeze
            % adjust inputs
            %input.lower_limit = input.lower_limit_BB;
            %input.upper_limit = input.upper_limit_BB;
            
            app.type = 'bb';
            app.quantile = 100 - app.Higherquantile.Value;
            [days_sunBB, days_windBB, days_BB] = weather_day_identifier(app);
            
%             dt = datetime(days_BB(:,1:end-4));
%             days_BB(:,end+1) = day(dt, 'dayofyear');
%             clear dt
            
            %%
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            if app.SolarwindcombinedButton.Value
                
            plot_days(app, days_DD, days_BB);
            
            [impact_DD, impact_BB] = weather_impact(app, days_DD, days_BB);
            impact_plot_II(impact_DD, impact_BB);
            
            elseif app.SolaronlyButton.Value
                if sum(days_sunDD(:,end-2)) == 0
                    answer = questdlg(['Hourly solar radiation data contains more values == 0 ' ...
                        'as the choosen quantile size is large. To display extreme values of the ' ...
                        'whole year, choose a larger quantile, or a smaller period. '], ...
                        'Warning','Continue', 'Adjust parameters', 'Continue');
                    if strcmp(answer, 'Adjust parameters')
                        return
                    end
                    clear answer
                end
            plot_days(app, days_sunDD, days_sunBB);
            
            [impact_DD, impact_BB] = weather_impact(app, days_sunDD, days_sunBB);
            impact_plot_II(impact_DD, impact_BB);  
            
            elseif app.WindonlyButton.Value
            plot_days(app, days_windDD, days_windBB);
            
            [impact_DD, impact_BB] = weather_impact(app, days_windDD, days_windBB);
            impact_plot_II(impact_DD, impact_BB);            
            end
            
        end

        % Button pushed function: dependent
        function dependentButtonPushed(app, event)
            [app.dependent.Text, app.path] = uigetfile({'*.mat'}, 'Select dependent variable');
            app.dependent.FontWeight = 'Normal';
        end

        % Button pushed function: ExplanatoryVar1
        function ExplanatoryVar1ButtonPushed(app, event)
            [app.ExplanatoryVar1.Text, ~] = uigetfile({'*.mat'}, 'Select explanatory solar variable');  
        end

        % Button pushed function: ExplanatoryVar2
        function ExplanatoryVar2ButtonPushed(app, event)
            [app.ExplanatoryVar2.Text, ~] = uigetfile({'*.mat'}, 'Select explanatory wind variable');  
        end

        % Button pushed function: ClosecurrentMATLABfiguresButton
        function ClosecurrentMATLABfiguresButtonPushed(app, event)
            close all
            clear all 
            clc
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FirstyearEditFieldLabel = uilabel(app.UIFigure);
            app.FirstyearEditFieldLabel.Text = 'First year';
            app.firstyear = uieditfield(app.UIFigure, 'numeric');
            app.firstyear.Value = 2013;
            app.LastyearEditFieldLabel = uilabel(app.UIFigure);
            app.LastyearEditFieldLabel.Text = 'Last year';
            app.lastyear = uieditfield(app.UIFigure, 'numeric');
            app.lastyear.Value = 2016;
            app.RunObservationButton = uibutton(app.UIFigure, 'push');
            app.RunObservationButton.ButtonPushedFcn = createCallbackFcn(app, @RunObservationButtonPushed, true);
            app.RunObservationButton.Text = 'Run observation';
            app.LowerquantileEditFieldLabel = uilabel(app.UIFigure);
            app.LowerquantileEditFieldLabel.Text = 'Lower quantile';
            app.Lowerquantile = uieditfield(app.UIFigure, 'numeric');
            app.Lowerquantile.Value = 10;
            app.HigherquantileEditFieldLabel = uilabel(app.UIFigure);
            app.HigherquantileEditFieldLabel.Text = 'Higher quantile';
            app.Higherquantile = uieditfield(app.UIFigure, 'numeric');
            app.Higherquantile.Value = 90;
            app.dependent = uibutton(app.UIFigure, 'push');
            app.dependent.ButtonPushedFcn = createCallbackFcn(app, @dependentButtonPushed, true);
            app.dependent.Text = 'Browse variable...';
            app.DependentvariableLabel = uilabel(app.UIFigure);
            app.DependentvariableLabel.Text = 'Dependent variable';
            app.ObservationtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.ObservationtypeButtonGroup.Title = 'Observation type';
            app.SolarwindcombinedButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.SolarwindcombinedButton.Text = 'Solar & wind combined';
            app.SolarwindcombinedButton.Value = true;
            app.SolaronlyButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.SolaronlyButton.Text = 'Solar only';
            app.WindonlyButton = uiradiobutton(app.ObservationtypeButtonGroup);
            app.WindonlyButton.Text = 'Wind only';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Intensity of wind speed on detected events')
            xlabel(app.UIAxes, 'Day of year')
            ylabel(app.UIAxes, 'Wind intensity [%]')
            app.UIAxes.XLim = [1 366];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.XTick = [1 100 200 300];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Intensity of solar radiation on detected events')
            xlabel(app.UIAxes2, 'Day of year')
            ylabel(app.UIAxes2, 'Solar intensity [%]')
            app.UIAxes2.XLim = [1 366];
            app.UIAxes2.YLim = [0 100];
            app.UIAxes2.XTick = [1 100 200 300];
            app.ExplanatoryVar1 = uibutton(app.UIFigure, 'push');
            app.ExplanatoryVar1.ButtonPushedFcn = createCallbackFcn(app, @ExplanatoryVar1ButtonPushed, true);
            app.ExplanatoryVar1.Text = 'radiation_sum_DE_daily.mat';
            app.ExplanatoryVar2 = uibutton(app.UIFigure, 'push');
            app.ExplanatoryVar2.ButtonPushedFcn = createCallbackFcn(app, @ExplanatoryVar2ButtonPushed, true);
            app.ExplanatoryVar2.Text = 'windspeed_DE_daily.mat';
            app.ExplanatoryvariablesolarLabel_2 = uilabel(app.UIFigure);
            app.ExplanatoryvariablesolarLabel_2.Text = 'Explanatory variable | solar';
            app.ExplanatoryvariablewindLabel_2 = uilabel(app.UIFigure);
            app.ExplanatoryvariablewindLabel_2.Text = 'Explanatory variable | wind';
            app.ClosecurrentMATLABfiguresButton = uibutton(app.UIFigure, 'push');
            app.ClosecurrentMATLABfiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ClosecurrentMATLABfiguresButtonPushed, true);
            app.ClosecurrentMATLABfiguresButton.Text = 'Close current MATLAB-figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = weather_impact_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end